classdef oct < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        FilesTab                        matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        SelectfilesButton               matlab.ui.control.Button
        ListBox_2                       matlab.ui.control.ListBox
        CameraTab                       matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        CamlistRefresh                  matlab.ui.control.Button
        Camlist                         matlab.ui.control.DropDown
        AngleLabel                      matlab.ui.control.Label
        CamEndAngle                     matlab.ui.control.Spinner
        SlicesSpinnerLabel              matlab.ui.control.Label
        CamSlices                       matlab.ui.control.Spinner
        StartCamera                     matlab.ui.control.Button
        PreprocessingPanel              matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        ReconstructionColor             matlab.ui.container.ButtonGroup
        GrayscaleButton                 matlab.ui.control.RadioButton
        RGBButton                       matlab.ui.control.RadioButton
        PreprocessingScale              matlab.ui.control.Spinner
        ScaleSpinnerLabel               matlab.ui.control.Label
        PreprocessButton                matlab.ui.control.Button
        ReconstructionPanel             matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        AngleSpinner                    matlab.ui.control.Spinner
        AngleSpinnerLabel               matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        FBPTab                          matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        InterpolationDropDownLabel      matlab.ui.control.Label
        InterpolationDropDown           matlab.ui.control.DropDown
        FilterDropDownLabel             matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        ReconstructButton               matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        AxisViewTab                     matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        SliceSpinnerLabel               matlab.ui.control.Label
        SliceSpinner                    matlab.ui.control.Spinner
        AxisButtonGroup                 matlab.ui.container.ButtonGroup
        Ax1Button                       matlab.ui.control.RadioButton
        Ax2Button                       matlab.ui.control.RadioButton
        Ax3Button                       matlab.ui.control.RadioButton
        RefreshButton                   matlab.ui.control.Button
        VolTab                          matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        DownscaleSpinnerLabel           matlab.ui.control.Label
        VolumetricDownscale             matlab.ui.control.Spinner
        NoisethresholdLabel             matlab.ui.control.Label
        VolumetricThreshold             matlab.ui.control.Spinner
        VolumetricRender                matlab.ui.control.Button
        IsoSurfaceTab                   matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        DownscaleSpinnerLabel_2         matlab.ui.control.Label
        IsoDownscale                    matlab.ui.control.Spinner
        NoisethresholdSpinnerLabel      matlab.ui.control.Label
        IsoNoiseThreshold               matlab.ui.control.Spinner
        IntensitythresholdSpinnerLabel  matlab.ui.control.Label
        IsoIntensityThreshold           matlab.ui.control.Spinner
        IsoRender                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Toolbar                         matlab.ui.container.Toolbar
        NewSession                      matlab.ui.container.toolbar.PushTool
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        ActiveSession = Session() % Description
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfilesButton
        function on_browse_files(app, event)
            [files, path] = uigetfile('MultiSelect', 'on');
            if isequal(files, 0)
                disp('No files selecter') % display error msg (todo)
                return
            end
            app.ActiveSession.from_array(load_images(strcat(path, files)));
        end

        % Clicked callback: NewSession
        function on_new_session(app, event)
            app.ActiveSession = Session;
        end

        % Button pushed function: ReconstructButton
        function on_reconstruct(app, event)
            angle = app.AngleSpinner.Value;
            app.ActiveSession.Angle = angle;
            app.ActiveSession.fbp()
        end

        % Callback function: AxisButtonGroup, RefreshButton, 
        % SliceSpinner
        function on_axis_plot_refresh(app, event)
            n = app.SliceSpinner.Value;
            ax = 1;
            switch app.AxisButtonGroup.SelectedObject
                case app.Ax1Button
                    ax = 1;
                case app.Ax2Button
                    ax = 2;
                case app.Ax3Button
                    ax = 3;
            end
            I = squeeze(app.ActiveSession.get_slice(n, ax));
            cla(app.UIAxes);
            imshow(I, 'Parent', app.UIAxes);
            view(app.UIAxes, 2)
        end

        % Button pushed function: CamlistRefresh
        function on_camlist_refresh(app, event)
            app.Camlist.Items = get_camlist();
        end

        % Button pushed function: StartCamera
        function on_start_camera(app, event)
            % get device name
            devname = app.Camlist.Value;
            % check if device is available
            devlist = get_camlist();
            if ~nnz(devlist==devname)
                error('Selected video device not available')
            end
            % get other parameters
            start_angle = app.CamStartAngle.Value;
            end_angle = app.CamEndAngle.Value;
            nslices = app.CamSlices.Value;
            % get device
            device = webcam(devname);
            % get camera resolution
            res = device.Resolution;
            pos = strfind(res, 'x');
            res = [str2double(res(pos+1:end)) str2double(res(1:pos-1))];
            
        end

        % Button pushed function: PreprocessButton
        function on_preprocess(app, event)
            scale = app.PreprocessingScale.Value;
            grayscale = (app.ReconstructionColor.SelectedObject...
                == app.GrayscaleButton); % get grayscale option
            app.ActiveSession.Preprocessed = prepims(...
                app.ActiveSession.Projections, scale, grayscale);
        end

        % Button pushed function: VolumetricRender
        function on_volumetric_render(app, event)
            downscale = app.VolumetricDownscale.Value;
            threshold = app.VolumetricThreshold.Value;
            [FBdown,~,~,~]= render_vol(app.ActiveSession.Reconstruction,...
                downscale, threshold);
            
            cla(app.UIAxes);
            axes_h = app.UIAxes;
            vol3d('cdata', FBdown, 'xdata', [0 1], 'ydata',...
                [0 1], 'zdata', [0 1], 'Parent', axes_h);
            axes_h.Alphamap = [0 linspace(0.1, 0, 256)];
            axes_h.Colormap = jet;
            view(axes_h, 3);
        end

        % Button pushed function: IsoRender
        function on_iso_render(app, event)
            [FBdown,x,y,z] = render_vol(app.ActiveSession.Reconstruction,...
                app.IsoDownscale.Value, app.IsoNoiseThreshold.Value);
            
            intensityThreshold = app.IsoIntensityThreshold.Value; % add handler (todo)
            
            cla(app.UIAxes);
            p = patch(app.UIAxes, isosurface(x,y,z,FBdown,intensityThreshold));
            isonormals(x,y,z,FBdown,p)
            p.EdgeColor = 'none';
            p.FaceColor = 'red';
            p.Marker = 'none';
            p.FaceAlpha = 0.2;
            daspect(app.UIAxes, [1 1 1]);
            view(app.UIAxes, 3);
            camlight(app.UIAxes);
            lighting(app.UIAxes, 'gouraud')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {568, 568};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.TabGroup3 = uitabgroup(app.GridLayout2);
            app.FilesTab = uitab(app.TabGroup3);
            app.FilesTab.Title = 'Files';
            app.GridLayout6 = uigridlayout(app.FilesTab);
            app.SelectfilesButton = uibutton(app.GridLayout6, 'push');
            app.SelectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @on_browse_files, true);
            app.SelectfilesButton.Text = 'Select files';
            app.ListBox_2 = uilistbox(app.GridLayout6);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.CameraTab = uitab(app.TabGroup3);
            app.CameraTab.Title = 'Camera';
            app.GridLayout8 = uigridlayout(app.CameraTab);
            app.CamlistRefresh = uibutton(app.GridLayout8, 'push');
            app.CamlistRefresh.ButtonPushedFcn = createCallbackFcn(app, @on_camlist_refresh, true);
            app.CamlistRefresh.Text = 'Refresh';
            app.Camlist = uidropdown(app.GridLayout8);
            app.Camlist.Items = {'None'};
            app.Camlist.Value = 'None';
            app.AngleLabel = uilabel(app.GridLayout8);
            app.AngleLabel.Text = 'Angle';
            app.CamEndAngle = uispinner(app.GridLayout8);
            app.SlicesSpinnerLabel = uilabel(app.GridLayout8);
            app.SlicesSpinnerLabel.Text = 'Slices';
            app.CamSlices = uispinner(app.GridLayout8);
            app.StartCamera = uibutton(app.GridLayout8, 'push');
            app.StartCamera.ButtonPushedFcn = createCallbackFcn(app, @on_start_camera, true);
            app.StartCamera.Text = 'Start';
            app.PreprocessingPanel = uipanel(app.GridLayout2);
            app.PreprocessingPanel.Title = 'Preprocessing';
            app.GridLayout9 = uigridlayout(app.PreprocessingPanel);
            app.ReconstructionColor = uibuttongroup(app.GridLayout9);
            app.GrayscaleButton = uiradiobutton(app.ReconstructionColor);
            app.GrayscaleButton.Text = 'Grayscale';
            app.GrayscaleButton.Value = true;
            app.RGBButton = uiradiobutton(app.ReconstructionColor);
            app.RGBButton.Text = 'RGB';
            app.PreprocessingScale = uispinner(app.GridLayout9);
            app.PreprocessingScale.Step = 0.1;
            app.PreprocessingScale.Limits = [0.1 1];
            app.PreprocessingScale.Value = 0.5;
            app.ScaleSpinnerLabel = uilabel(app.GridLayout9);
            app.ScaleSpinnerLabel.Text = 'Scale';
            app.PreprocessButton = uibutton(app.GridLayout9, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @on_preprocess, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.ReconstructionPanel = uipanel(app.GridLayout2);
            app.ReconstructionPanel.Title = 'Reconstruction';
            app.GridLayout10 = uigridlayout(app.ReconstructionPanel);
            app.AngleSpinner = uispinner(app.GridLayout10);
            app.AngleSpinner.Value = 360;
            app.AngleSpinnerLabel = uilabel(app.GridLayout10);
            app.AngleSpinnerLabel.Text = 'Angle';
            app.MethodDropDown = uidropdown(app.GridLayout10);
            app.MethodDropDown.Items = {'FBP'};
            app.MethodDropDown.Value = 'FBP';
            app.MethodDropDownLabel = uilabel(app.GridLayout10);
            app.MethodDropDownLabel.Text = 'Method';
            app.TabGroup = uitabgroup(app.GridLayout10);
            app.FBPTab = uitab(app.TabGroup);
            app.FBPTab.Title = 'FBP';
            app.GridLayout4 = uigridlayout(app.FBPTab);
            app.InterpolationDropDownLabel = uilabel(app.GridLayout4);
            app.InterpolationDropDownLabel.Text = 'Interpolation';
            app.InterpolationDropDown = uidropdown(app.GridLayout4);
            app.InterpolationDropDown.Items = {'nearest', 'linear', 'spline', 'vchip', 'v5cubic'};
            app.InterpolationDropDown.Value = 'nearest';
            app.FilterDropDownLabel = uilabel(app.GridLayout4);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.GridLayout4);
            app.FilterDropDown.Items = {'Ram-Lak', 'Shepp-Logan', 'Cosine', 'Hamming', 'Hann', 'None', ''};
            app.FilterDropDown.Value = 'Ram-Lak';
            app.ReconstructButton = uibutton(app.GridLayout10, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @on_reconstruct, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.AxisViewTab = uitab(app.TabGroup2);
            app.AxisViewTab.Title = 'Axis View';
            app.GridLayout5 = uigridlayout(app.AxisViewTab);
            app.SliceSpinnerLabel = uilabel(app.GridLayout5);
            app.SliceSpinnerLabel.Text = 'Slice';
            app.SliceSpinner = uispinner(app.GridLayout5);
            app.SliceSpinner.Limits = [1 Inf];
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @on_axis_plot_refresh, true);
            app.SliceSpinner.Value = 100;
            app.AxisButtonGroup = uibuttongroup(app.GridLayout5);
            app.AxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @on_axis_plot_refresh, true);
            app.AxisButtonGroup.Title = 'Axis';
            app.Ax1Button = uiradiobutton(app.AxisButtonGroup);
            app.Ax1Button.Text = 'Ax1';
            app.Ax1Button.Value = true;
            app.Ax2Button = uiradiobutton(app.AxisButtonGroup);
            app.Ax2Button.Text = 'Ax2';
            app.Ax3Button = uiradiobutton(app.AxisButtonGroup);
            app.Ax3Button.Text = 'Ax3';
            app.RefreshButton = uibutton(app.GridLayout5, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @on_axis_plot_refresh, true);
            app.RefreshButton.Text = 'Refresh';
            app.VolTab = uitab(app.TabGroup2);
            app.VolTab.Title = 'Volumetric';
            app.GridLayout11 = uigridlayout(app.VolTab);
            app.DownscaleSpinnerLabel = uilabel(app.GridLayout11);
            app.DownscaleSpinnerLabel.Text = 'Downscale';
            app.VolumetricDownscale = uispinner(app.GridLayout11);
            app.VolumetricDownscale.Step = 0.5;
            app.VolumetricDownscale.Limits = [1 Inf];
            app.VolumetricDownscale.Value = 2;
            app.NoisethresholdLabel = uilabel(app.GridLayout11);
            app.NoisethresholdLabel.Text = 'Noise threshold';
            app.VolumetricThreshold = uispinner(app.GridLayout11);
            app.VolumetricThreshold.Step = 0.2;
            app.VolumetricThreshold.Limits = [0 Inf];
            app.VolumetricThreshold.Value = 1.8;
            app.VolumetricRender = uibutton(app.GridLayout11, 'push');
            app.VolumetricRender.ButtonPushedFcn = createCallbackFcn(app, @on_volumetric_render, true);
            app.VolumetricRender.Text = 'Render';
            app.IsoSurfaceTab = uitab(app.TabGroup2);
            app.IsoSurfaceTab.Title = 'IsoSurface';
            app.GridLayout12 = uigridlayout(app.IsoSurfaceTab);
            app.DownscaleSpinnerLabel_2 = uilabel(app.GridLayout12);
            app.DownscaleSpinnerLabel_2.Text = 'Downscale';
            app.IsoDownscale = uispinner(app.GridLayout12);
            app.IsoDownscale.Step = 0.5;
            app.IsoDownscale.Limits = [1 Inf];
            app.IsoDownscale.Value = 2;
            app.NoisethresholdSpinnerLabel = uilabel(app.GridLayout12);
            app.NoisethresholdSpinnerLabel.Text = 'Noise threshold';
            app.IsoNoiseThreshold = uispinner(app.GridLayout12);
            app.IsoNoiseThreshold.Step = 0.2;
            app.IsoNoiseThreshold.Limits = [0 Inf];
            app.IsoNoiseThreshold.Value = 1.8;
            app.IntensitythresholdSpinnerLabel = uilabel(app.GridLayout12);
            app.IntensitythresholdSpinnerLabel.Text = 'Intensity threshold';
            app.IsoIntensityThreshold = uispinner(app.GridLayout12);
            app.IsoIntensityThreshold.Step = 0.5;
            app.IsoIntensityThreshold.Limits = [0 Inf];
            app.IsoIntensityThreshold.Value = 15;
            app.IsoRender = uibutton(app.GridLayout12, 'push');
            app.IsoRender.ButtonPushedFcn = createCallbackFcn(app, @on_iso_render, true);
            app.IsoRender.Text = 'Render';
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Preview')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Toolbar = uitoolbar(app.UIFigure);
            app.NewSession = uipushtool(app.Toolbar);
            app.NewSession.ClickedCallback = createCallbackFcn(app, @on_new_session, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oct

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end